classdef SCFit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SCFitUIFigure                   matlab.ui.Figure
        OpenAxes                        matlab.ui.control.UIAxes
        ShutAxes                        matlab.ui.control.UIAxes
        LoadandPreprocessDataPanel      matlab.ui.container.Panel
        LoadIdealizedButton             matlab.ui.control.Button
        ImposeResButton                 matlab.ui.control.Button
        OpeningResolutionmsEditFieldLabel  matlab.ui.control.Label
        OpenResms                       matlab.ui.control.NumericEditField
        ShutResolutionmsEditFieldLabel  matlab.ui.control.Label
        ShutResms                       matlab.ui.control.NumericEditField
        LoadandFitModelPanel            matlab.ui.container.Panel
        LoadModelButton                 matlab.ui.control.Button
        FitButton                       matlab.ui.control.Button
        FileEditFieldLabel              matlab.ui.control.Label
        FileName                        matlab.ui.control.EditField
    end


    properties (Access = private)
        amplitudes % Array of idealized single channel amplitudes
        durations  % Array of idealized single channel dwell times
        resolvedDwells % Array of resolved idealized dwell times
        resolvedStates % Array of resolved idealized amplitudes
    end


    methods (Access = private)

        % Button pushed function: LoadIdealizedButton
        function LoadIdealizedButtonPushed(app, event)

            [file, path, filterIndex] = uigetfile({'*.dwt','QuB Idealized Data';'*.scn','SCAN idealized data'},'MultiSelect','off');
            DWTFILE=false;
            if filterIndex==1
                DWTFILE=true;
            end
            
            % Read idealized data
            if DWTFILE
                [app.durations, app.amplitudes] = dwtread([path file]);
                if isinteger(app.amplitudes)
                    app.amplitudes=double(app.amplitudes);
                end
            else 
                [app.durations, app.amplitudes, ~, cal] = scanread([path file]);
                app.amplitudes=app.amplitudes*cal;
            end
            
            plothist(app.durations, app.amplitudes, 0.05, 0.1, [app.OpenAxes, app.ShutAxes], true);
            app.FileName.Value = [path, file];
        end

        % Value changed function: OpenResms
        function OpenResmsValueChanged(app, event)

        end

        % Button pushed function: ImposeResButton
        function ImposeResButtonPushed(app, event)
            if app.OpenResms.Value == 0 && app.ShutResms.Value == 0
                msg = sprintf('The open and shut time resolutions are both 0, hence there''s nothing to do');
                uialert(app.SCFitUIFigure, msg, 'Warning', 'Icon', 'warning');
            else
                [app.resolvedDwells, app.resolvedStates] = imposeres (app.durations, app.amplitudes, app.OpenResms.Value, app.ShutResms.Value);
                plothist(app.resolvedDwells, app.resolvedStates, 0.05, 0.1, [app.OpenAxes, app.ShutAxes], true);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SCFitUIFigure = uifigure;
            app.SCFitUIFigure.Name = 'SCFit';
            app.OpenAxes = uiaxes(app.SCFitUIFigure);
            title(app.OpenAxes, 'Openings')
            xlabel(app.OpenAxes, 'Time (ms)')
            ylabel(app.OpenAxes, 'Count')
            app.ShutAxes = uiaxes(app.SCFitUIFigure);
            title(app.ShutAxes, 'Closings')
            xlabel(app.ShutAxes, 'Time (ms)')
            ylabel(app.ShutAxes, 'Count')
            app.LoadandPreprocessDataPanel = uipanel(app.SCFitUIFigure);
            app.LoadandPreprocessDataPanel.Title = 'Load and Pre-process Data';
            app.LoadIdealizedButton = uibutton(app.LoadandPreprocessDataPanel, 'push');
            app.LoadIdealizedButton.ButtonPushedFcn = createCallbackFcn(app, @LoadIdealizedButtonPushed, true);
            app.LoadIdealizedButton.Text = 'Load Idealized';
            app.ImposeResButton = uibutton(app.LoadandPreprocessDataPanel, 'push');
            app.ImposeResButton.ButtonPushedFcn = createCallbackFcn(app, @ImposeResButtonPushed, true);
            app.ImposeResButton.Text = 'Impose Res';
            app.OpeningResolutionmsEditFieldLabel = uilabel(app.LoadandPreprocessDataPanel);
            app.OpeningResolutionmsEditFieldLabel.Text = 'Opening Resolution (ms)';
            app.OpenResms = uieditfield(app.LoadandPreprocessDataPanel, 'numeric');
            app.OpenResms.ValueChangedFcn = createCallbackFcn(app, @OpenResmsValueChanged, true);
            app.ShutResolutionmsEditFieldLabel = uilabel(app.LoadandPreprocessDataPanel);
            app.ShutResolutionmsEditFieldLabel.Text = 'Shut Resolution (ms)';
            app.ShutResms = uieditfield(app.LoadandPreprocessDataPanel, 'numeric');
            app.LoadandFitModelPanel = uipanel(app.SCFitUIFigure);
            app.LoadandFitModelPanel.Title = 'Load and Fit Model';
            app.LoadModelButton = uibutton(app.LoadandFitModelPanel, 'push');
            app.LoadModelButton.Text = 'Load Model';
            app.FitButton = uibutton(app.LoadandFitModelPanel, 'push');
            app.FitButton.Text = 'Fit';
            app.FileEditFieldLabel = uilabel(app.SCFitUIFigure);
            app.FileEditFieldLabel.Text = 'File:';
            app.FileName = uieditfield(app.SCFitUIFigure, 'text');
            app.FileName.Editable = 'off';
            app.FileName.Value = '<FILENAME>';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SCFit

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SCFitUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SCFitUIFigure)
        end
    end
end